classdef laiiqautilities_win64 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ArchivoMenu              matlab.ui.container.Menu
        NuevoMenu                matlab.ui.container.Menu
        CineticaOzonizacionMenu  matlab.ui.container.Menu
        VisorOzonogramaMenu      matlab.ui.container.Menu
        VisorEspectroUVVisMenu   matlab.ui.container.Menu
        BiorreaccionMenu         matlab.ui.container.Menu
        ReporteMenu              matlab.ui.container.Menu
        AbrirMenu                matlab.ui.container.Menu
        OzonogramaMenu           matlab.ui.container.Menu
        EspectroUVVisMenu        matlab.ui.container.Menu
        BiorreaccionMenu2        matlab.ui.container.Menu
        GuardarMenu              matlab.ui.container.Menu
        GuardarcomoMenu          matlab.ui.container.Menu
        SalirMenu                matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        CineticaOzonizacionTab   matlab.ui.container.Tab
        CineticaButton           matlab.ui.control.Button
        HTML1                    matlab.ui.control.HTML
        CrearnuevoLabel_14       matlab.ui.control.Label
        TabGroup1                matlab.ui.container.TabGroup
        CineticaTab              matlab.ui.container.Tab
        Lamp                     matlab.ui.control.Lamp
        MonitorLabel             matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        ArduinoDropDownLabel     matlab.ui.control.Label
        ArduinoDropDown          matlab.ui.control.DropDown
        Panel_2                  matlab.ui.container.Panel
        unidadestiempoDropDown   matlab.ui.control.DropDown
        TiempoEditFieldLabel     matlab.ui.control.Label
        TiempoEditField          matlab.ui.control.NumericEditField
        GananciaSpinnerLabel     matlab.ui.control.Label
        GananciaSpinner          matlab.ui.control.Spinner
        Panel_3                  matlab.ui.container.Panel
        IniciarButton            matlab.ui.control.Button
        DisplayEditField         matlab.ui.control.NumericEditField
        Panel_4                  matlab.ui.container.Panel
        GuardarButton            matlab.ui.control.Button
        MostrarButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        OzonogramaTab            matlab.ui.container.Tab
        OzonogramaButton         matlab.ui.control.Button
        CrearnuevoLabel_15       matlab.ui.control.Label
        HTML2                    matlab.ui.control.HTML
        TabGroup2                matlab.ui.container.TabGroup
        OzonogramaTab2           matlab.ui.container.Tab
        EspectroUVTab            matlab.ui.container.Tab
        EspectroButton           matlab.ui.control.Button
        CrearnuevoLabel_16       matlab.ui.control.Label
        HTML3                    matlab.ui.control.HTML
        TabGroup3                matlab.ui.container.TabGroup
        EspectroTab              matlab.ui.container.Tab
        BiorreaccionTab          matlab.ui.container.Tab
        BiorreaccionButton       matlab.ui.control.Button
        CrearnuevoLabel_17       matlab.ui.control.Label
        HTML4                    matlab.ui.control.HTML
        TabGroup4                matlab.ui.container.TabGroup
        BioreaccionTab           matlab.ui.container.Tab
        ReporteTab               matlab.ui.container.Tab
        ReporteButton            matlab.ui.control.Button
        CrearnuevoLabel_18       matlab.ui.control.Label
        HTML5                    matlab.ui.control.HTML
        TabGroup5                matlab.ui.container.TabGroup
        ReporteTab5              matlab.ui.container.Tab
        InstruccionesTab         matlab.ui.container.Tab
        HTML7                    matlab.ui.control.HTML
        HTML6                    matlab.ui.control.HTML
        AcercadeTab              matlab.ui.container.Tab
        HTML9                    matlab.ui.control.HTML
        HTML8                    matlab.ui.control.HTML
    end

    
    properties (Access = private)
        fig
        tg
        tabcount
        tab
    end
    
    methods (Access = public)
        
        function closeTab(app,src,event)
            delete(app.tg.SelectedTab)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % Visibilidad de TabsGroups apagada
            app.TabGroup1.Visible = 'off';
            
            %             app.UIAxes.XLabel.Color = 'green';
            app.UIAxes.XColor = 'green';
            app.UIAxes.YColor = 'g';
            
            app.UIAxes.XLabel.Interpreter = 'tex';
            app.UIAxes.YLabel.Interpreter = 'tex';
            
            app.UIAxes.XLabel.String = 'Tiempo (min)';
            app.UIAxes.YLabel.String = "Concentración [g/Nm^{3}]";
            
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            %             left = screenWidth*0.1;
            %             bottom = screenHeight*0.1;
            width = screenWidth*0.9;
            height = screenHeight*0.85;
            left = width*0.05;
            bottom = height*0.08;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: CineticaButton
        function CineticaButtonPushed(app, event)
            app.TabGroup1.Visible = 'on';
            app.TabGroup1.Position = app.TabGroup.Position;
            %             app.ArduinoDropDown.Position = [73 522 95 22];
        end

        % Button pushed function: OzonogramaButton
        function OzonogramaButtonPushed(app, event)
            app.TabGroup2.Visible = 'on';
            app.TabGroup2.Position = app.TabGroup.Position;
        end

        % Button pushed function: EspectroButton
        function EspectroButtonPushed(app, event)
            app.TabGroup3.Visible = 'on';
            app.TabGroup3.Position = app.TabGroup.Position;
        end

        % Button pushed function: BiorreaccionButton
        function BiorreaccionButtonPushed(app, event)
            app.TabGroup4.Visible = 'on';
            app.TabGroup4.Position = app.TabGroup.Position;
        end

        % Button pushed function: ReporteButton
        function ReporteButtonPushed(app, event)
            app.TabGroup5.Visible = 'on';
            app.TabGroup5.Position = app.TabGroup.Position;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.NuevoMenu = uimenu(app.ArchivoMenu);
            app.NuevoMenu.Text = 'Nuevo';
            app.CineticaOzonizacionMenu = uimenu(app.NuevoMenu);
            app.CineticaOzonizacionMenu.Text = 'Cinética Ozonización';
            app.VisorOzonogramaMenu = uimenu(app.NuevoMenu);
            app.VisorOzonogramaMenu.Text = 'Visor Ozonograma';
            app.VisorEspectroUVVisMenu = uimenu(app.NuevoMenu);
            app.VisorEspectroUVVisMenu.Text = 'Visor EspectroUV-Vis';
            app.BiorreaccionMenu = uimenu(app.NuevoMenu);
            app.BiorreaccionMenu.Text = 'Biorreacción';
            app.ReporteMenu = uimenu(app.NuevoMenu);
            app.ReporteMenu.Text = 'Reporte';
            app.AbrirMenu = uimenu(app.ArchivoMenu);
            app.AbrirMenu.Text = 'Abrir';
            app.OzonogramaMenu = uimenu(app.AbrirMenu);
            app.OzonogramaMenu.Text = 'Ozonograma';
            app.EspectroUVVisMenu = uimenu(app.AbrirMenu);
            app.EspectroUVVisMenu.Text = 'EspectroUV-Vis';
            app.BiorreaccionMenu2 = uimenu(app.AbrirMenu);
            app.BiorreaccionMenu2.Text = 'Biorreacción';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.Text = 'Guardar';
            app.GuardarcomoMenu = uimenu(app.ArchivoMenu);
            app.GuardarcomoMenu.Text = 'Guardar como';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.Text = 'Salir';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CineticaOzonizacionTab = uitab(app.TabGroup);
            app.CineticaOzonizacionTab.Title = 'Cinética Ozonización';
            app.CineticaButton = uibutton(app.CineticaOzonizacionTab, 'push');
            app.CineticaButton.ButtonPushedFcn = createCallbackFcn(app, @CineticaButtonPushed, true);
            app.CineticaButton.Text = 'Cinética';
            app.HTML1 = uihtml(app.CineticaOzonizacionTab);
            app.HTML1.HTMLSource = 'logo.html';
            app.CrearnuevoLabel_14 = uilabel(app.CineticaOzonizacionTab);
            app.CrearnuevoLabel_14.Text = 'Crear nuevo:';
            app.TabGroup1 = uitabgroup(app.CineticaOzonizacionTab);
            app.CineticaTab = uitab(app.TabGroup1);
            app.CineticaTab.Title = 'Cinética';
            app.Lamp = uilamp(app.CineticaTab);
            app.MonitorLabel = uilabel(app.CineticaTab);
            app.MonitorLabel.Text = '>> Monitor Label';
            app.Panel = uipanel(app.CineticaTab);
            app.ArduinoDropDownLabel = uilabel(app.Panel);
            app.ArduinoDropDownLabel.Text = 'Arduino';
            app.ArduinoDropDown = uidropdown(app.Panel);
            app.ArduinoDropDown.Items = {'COM3'};
            app.ArduinoDropDown.Value = 'COM3';
            app.Panel_2 = uipanel(app.CineticaTab);
            app.unidadestiempoDropDown = uidropdown(app.Panel_2);
            app.unidadestiempoDropDown.Items = {'min', 'hr', 'seg'};
            app.unidadestiempoDropDown.Value = 'min';
            app.TiempoEditFieldLabel = uilabel(app.Panel_2);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.Panel_2, 'numeric');
            app.TiempoEditField.Value = 60;
            app.GananciaSpinnerLabel = uilabel(app.Panel_2);
            app.GananciaSpinnerLabel.Text = 'Ganancia';
            app.GananciaSpinner = uispinner(app.Panel_2);
            app.GananciaSpinner.Step = 0.0001;
            app.GananciaSpinner.ValueDisplayFormat = '%.4f';
            app.GananciaSpinner.Value = 0.1674;
            app.Panel_3 = uipanel(app.CineticaTab);
            app.IniciarButton = uibutton(app.Panel_3, 'push');
            app.IniciarButton.Text = 'Iniciar';
            app.DisplayEditField = uieditfield(app.Panel_3, 'numeric');
            app.Panel_4 = uipanel(app.CineticaTab);
            app.GuardarButton = uibutton(app.Panel_4, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.MostrarButton = uibutton(app.Panel_4, 'push');
            app.MostrarButton.Text = 'Mostrar';
            app.UIAxes = uiaxes(app.CineticaTab);
            app.OzonogramaTab = uitab(app.TabGroup);
            app.OzonogramaTab.Title = 'Ozonograma';
            app.OzonogramaButton = uibutton(app.OzonogramaTab, 'push');
            app.OzonogramaButton.ButtonPushedFcn = createCallbackFcn(app, @OzonogramaButtonPushed, true);
            app.OzonogramaButton.Text = 'Ozonograma';
            app.CrearnuevoLabel_15 = uilabel(app.OzonogramaTab);
            app.CrearnuevoLabel_15.Text = 'Crear nuevo:';
            app.HTML2 = uihtml(app.OzonogramaTab);
            app.HTML2.HTMLSource = 'logo.html';
            app.TabGroup2 = uitabgroup(app.OzonogramaTab);
            app.TabGroup2.Visible = 'off';
            app.OzonogramaTab2 = uitab(app.TabGroup2);
            app.OzonogramaTab2.Title = 'Ozonograma';
            app.EspectroUVTab = uitab(app.TabGroup);
            app.EspectroUVTab.Title = 'Espectro UV-Vis';
            app.EspectroButton = uibutton(app.EspectroUVTab, 'push');
            app.EspectroButton.ButtonPushedFcn = createCallbackFcn(app, @EspectroButtonPushed, true);
            app.EspectroButton.Text = 'Espectro UV-Vis';
            app.CrearnuevoLabel_16 = uilabel(app.EspectroUVTab);
            app.CrearnuevoLabel_16.Text = 'Crear nuevo:';
            app.HTML3 = uihtml(app.EspectroUVTab);
            app.HTML3.HTMLSource = 'logo.html';
            app.TabGroup3 = uitabgroup(app.EspectroUVTab);
            app.TabGroup3.Visible = 'off';
            app.EspectroTab = uitab(app.TabGroup3);
            app.EspectroTab.Title = 'Espectro UV-Vis';
            app.BiorreaccionTab = uitab(app.TabGroup);
            app.BiorreaccionTab.Title = 'Biorreacción';
            app.BiorreaccionButton = uibutton(app.BiorreaccionTab, 'push');
            app.BiorreaccionButton.ButtonPushedFcn = createCallbackFcn(app, @BiorreaccionButtonPushed, true);
            app.BiorreaccionButton.Text = 'Biorreacción';
            app.CrearnuevoLabel_17 = uilabel(app.BiorreaccionTab);
            app.CrearnuevoLabel_17.Text = 'Crear nuevo:';
            app.HTML4 = uihtml(app.BiorreaccionTab);
            app.HTML4.HTMLSource = 'logo.html';
            app.TabGroup4 = uitabgroup(app.BiorreaccionTab);
            app.TabGroup4.Visible = 'off';
            app.BioreaccionTab = uitab(app.TabGroup4);
            app.BioreaccionTab.Title = 'Biorreacción';
            app.ReporteTab = uitab(app.TabGroup);
            app.ReporteTab.Title = 'Reporte';
            app.ReporteButton = uibutton(app.ReporteTab, 'push');
            app.ReporteButton.ButtonPushedFcn = createCallbackFcn(app, @ReporteButtonPushed, true);
            app.ReporteButton.Text = 'Reporte';
            app.CrearnuevoLabel_18 = uilabel(app.ReporteTab);
            app.CrearnuevoLabel_18.Text = 'Crear nuevo:';
            app.HTML5 = uihtml(app.ReporteTab);
            app.HTML5.HTMLSource = 'logo.html';
            app.TabGroup5 = uitabgroup(app.ReporteTab);
            app.TabGroup5.Visible = 'off';
            app.ReporteTab5 = uitab(app.TabGroup5);
            app.ReporteTab5.Title = 'Reporte';
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.HTML7 = uihtml(app.InstruccionesTab);
            app.HTML7.HTMLSource = 'logo.html';
            app.HTML6 = uihtml(app.InstruccionesTab);
            app.HTML6.HTMLSource = 'instructions.html';
            app.AcercadeTab = uitab(app.TabGroup);
            app.AcercadeTab.Title = 'Acerca de';
            app.HTML9 = uihtml(app.AcercadeTab);
            app.HTML9.HTMLSource = 'about.html';
            app.HTML8 = uihtml(app.AcercadeTab);
            app.HTML8.HTMLSource = 'logo.html';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laiiqautilities_win64

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end